classdef App_Camera_Video < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        PlayimageButton        matlab.ui.control.Button
        StopimageButton        matlab.ui.control.Button
        PlaypreviewButton      matlab.ui.control.Button
        StoppreviewButton      matlab.ui.control.Button
        PlayvideoplayerButton  matlab.ui.control.Button
        StopvideoplayerButton  matlab.ui.control.Button
        RightPanel             matlab.ui.container.Panel
        axes                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        stopflag=0;
        cam=[];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.axes,'image')
        end

        % Button pushed function: PlayimageButton
        function PlayimageButtonPushed(app, event)
            
            % open webcam
            if isempty(app.cam)
                app.cam=webcam(1);
            end
            
            % hide the buttons
            app.PlayimageButton.Enable='off'; 
            app.PlaypreviewButton.Enable='off';
            app.PlayvideoplayerButton.Enable='off';
            app.StoppreviewButton.Enable='off';
            app.StopvideoplayerButton.Enable='off';
            
            % frame loop (until stop flag is on)          
            while app.stopflag==0
                
                % get snapshot
                frame = snapshot(app.cam);
                
                % show spapshot on axis
                imshow(frame,'Parent',app.axes);
                drawnow;
            end
            
            % restart stop flag
            app.stopflag=0;
            
            % enable buttons
            app.PlayimageButton.Enable='on'; 
            app.PlaypreviewButton.Enable='on';
            app.PlayvideoplayerButton.Enable='on';
            app.StoppreviewButton.Enable='on';
            app.StopvideoplayerButton.Enable='on';
            app.StopimageButton.Enable='on';            
        end

        % Button pushed function: StopimageButton
        function StopimageButtonPushed(app, event)
            
            % turn on stop flag
            app.stopflag=1;
        end

        % Button pushed function: PlaypreviewButton
        function PlaypreviewButtonPushed(app, event)
            
            % open webcam
            if isempty(app.cam) 
                app.cam=webcam(1);
            end
            
            % hide the buttons
            app.PlayimageButton.Enable='off'; 
            app.PlaypreviewButton.Enable='off';
            app.PlayvideoplayerButton.Enable='off';
            app.StopimageButton.Enable='off';
            app.StopvideoplayerButton.Enable='off';
            
            % create the image on axis
            frame0 = snapshot(app.cam);
            hImage = image(app.axes,zeros(size(frame0)));
            
            % preview video
            preview(app.cam,hImage);
        end

        % Button pushed function: StoppreviewButton
        function StoppreviewButtonPushed(app, event)
                
            closePreview(app.cam); % close preview

            % enable buttons
            app.PlayimageButton.Enable='on'; 
            app.PlaypreviewButton.Enable='on';
            app.PlayvideoplayerButton.Enable='on';
            app.StoppreviewButton.Enable='on';
            app.StopvideoplayerButton.Enable='on';
            app.StopimageButton.Enable='on';            
        end

        % Button pushed function: PlayvideoplayerButton
        function PlayvideoplayerButtonPushed(app, event)
            
            % open webcam
            if isempty(app.cam)
                app.cam=webcam(1);
            end
            
            % hide the buttons
            app.PlayimageButton.Enable='off'; 
            app.PlaypreviewButton.Enable='off';
            app.PlayvideoplayerButton.Enable='off';
            app.StopimageButton.Enable='off';
            app.StoppreviewButton.Enable='off';
            
            videoPlayer = vision.VideoPlayer();
            
            % frame loop (until stop flag is on)
            while app.stopflag==0
                
                % get snapshot
                frame = snapshot(app.cam);
                
                % load snapshot on videoplayer
                step(videoPlayer, frame);
            end
            
            % restart stop flag
            app.stopflag=0;
            
            % enable buttons
            app.PlayimageButton.Enable='on'; 
            app.PlaypreviewButton.Enable='on';
            app.PlayvideoplayerButton.Enable='on';
            app.StoppreviewButton.Enable='on';
            app.StopvideoplayerButton.Enable='on';
            app.StopimageButton.Enable='on';            
        end

        % Button pushed function: StopvideoplayerButton
        function StopvideoplayerButtonPushed(app, event)

            % turn on stop flag
            app.stopflag=1;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {333, 333};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {133, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PlayimageButton = uibutton(app.LeftPanel, 'push');
            app.PlayimageButton.ButtonPushedFcn = createCallbackFcn(app, @PlayimageButtonPushed, true);
            app.PlayimageButton.Text = 'Play (image)';
            app.StopimageButton = uibutton(app.LeftPanel, 'push');
            app.StopimageButton.ButtonPushedFcn = createCallbackFcn(app, @StopimageButtonPushed, true);
            app.StopimageButton.Text = 'Stop (image)';
            app.PlaypreviewButton = uibutton(app.LeftPanel, 'push');
            app.PlaypreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PlaypreviewButtonPushed, true);
            app.PlaypreviewButton.Text = 'Play (preview)';
            app.StoppreviewButton = uibutton(app.LeftPanel, 'push');
            app.StoppreviewButton.ButtonPushedFcn = createCallbackFcn(app, @StoppreviewButtonPushed, true);
            app.StoppreviewButton.Text = 'Stop (preview)';
            app.PlayvideoplayerButton = uibutton(app.LeftPanel, 'push');
            app.PlayvideoplayerButton.ButtonPushedFcn = createCallbackFcn(app, @PlayvideoplayerButtonPushed, true);
            app.PlayvideoplayerButton.Text = 'Play (videoplayer)';
            app.StopvideoplayerButton = uibutton(app.LeftPanel, 'push');
            app.StopvideoplayerButton.ButtonPushedFcn = createCallbackFcn(app, @StopvideoplayerButtonPushed, true);
            app.StopvideoplayerButton.Text = 'Stop (videoplayer)';
            app.RightPanel = uipanel(app.GridLayout);
            app.axes = uiaxes(app.RightPanel);
            title(app.axes, '')
            xlabel(app.axes, '')
            ylabel(app.axes, '')
            app.axes.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Camera_Video

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end